classdef MLA_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StartButton                    matlab.ui.control.Button
        CarKilometersEditField         matlab.ui.control.NumericEditField
        CarKilometersEditFieldLabel    matlab.ui.control.Label
        CarYearofFabricationEditField  matlab.ui.control.NumericEditField
        CarYearofFabricationEditFieldLabel  matlab.ui.control.Label
        CarModelEditField              matlab.ui.control.EditField
        CarModelEditFieldLabel         matlab.ui.control.Label
        CarBrandEditField              matlab.ui.control.EditField
        CarBrandEditFieldLabel         matlab.ui.control.Label
        PriceEvaluateAppLabel          matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = public)
          CarsDataAfter;
          data;
          userBrand;
          userModel;
          userYear;
          userKilometers;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
      app.CarsDataAfter = readtable('CarsData After.csv');
      app.data = app.CarsDataAfter;


            tr = 0;
            % User input for the brand of the car
                app.userBrand = app.CarBrandEditField.Value;
                app.userBrand = strtrim(app.userBrand);  % Remove leading and trailing whitespaces
                dataBrandAsString = cellstr(app.data.Brand);  % Convert to lowercase for case-insensitive comparison

                % Check if there are any brands in the data that match the user brand 
                if any(strcmpi(dataBrandAsString, app.userBrand))
                    tr = tr+1;
                else
                    % Display an error dialog
                    errordlg('Invalid brand of car. Please be careful while typing the brand.', 'Error', 'modal');
                end


            % User input for the model of the car
                app.userModel = app.CarModelEditField.Value;
                app.userModel = strtrim(app.userModel);  % Remove leading and trailing whitespaces
                dataModelAsString = cellstr(app.data.Model);  % Convert to lowercase for case-insensitive comparison

                % Check if there are any models in the data that match the user model 
                if any(strcmpi(dataModelAsString, app.userModel))
                    tr = tr+1;
                else
                   % Display an error dialog
                    errordlg('Invalid model of car. Please be careful while typing the model.', 'Error', 'modal');
                end
            
    
            % User input for the year of the car
                app.userYear = app.CarYearofFabricationEditField.Value;      
                % Check if userYear is within a valid range            
                if app.userYear >= 2000 && app.userYear <= 2023
                    tr = tr+1;
                else
                   % Display an error dialog
                    errordlg('Invalid year. Please enter a year between 2000 and 2023.', 'Error', 'modal');
                end       
            

            % User input for the km of the car
                app.userKilometers = app.CarKilometersEditField.Value;            
                % Check if userKilometers is within a valid range
                if app.userKilometers >= 0
                    tr = tr+1;
                else
                    % Display an error dialog
                    errordlg('The number of kilometers cannot be negative. Try entering a valid number.', 'Error', 'modal');
               end
            
            
                if tr == 4
                     % Open the second tab 
                    secondTab = MLA_GUI_Tab2(app);  % Pass the instance of MLA_GUI to MLA_GUI_Tab2
                    secondTab.reciveData(app.userBrand, app.userModel, app.userYear, app.userKilometers);
                    secondTab.UIFigure.Visible = 'on';  % Make the second tab visible
                    app.UIFigure.Visible = 'off';  % Make the first tab invisible
    
                else
                    % Display an error dialog
                    errordlg('Data not found, try again.', 'Error', 'modal');      
                end


        end

        % Value changed function: CarBrandEditField
        function CarBrandEditFieldValueChanged(app, event)

    
        end

        % Value changed function: CarModelEditField
        function CarModelEditFieldValueChanged(app, event)
            

        end

        % Value changed function: CarYearofFabricationEditField
        function CarYearofFabricationEditFieldValueChanged(app, event)
            

        end

        % Value changed function: CarKilometersEditField
        function CarKilometersEditFieldValueChanged(app, event)
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'cars.jpeg');
            app.PriceEvaluateAppLabel = uilabel(app.UIFigure);
            app.PriceEvaluateAppLabel.Text = 'Price Evaluate App';
            app.CarBrandEditFieldLabel = uilabel(app.UIFigure);
            app.CarBrandEditFieldLabel.Text = 'Car Brand';
            app.CarBrandEditField = uieditfield(app.UIFigure, 'text');
            app.CarBrandEditField.ValueChangedFcn = createCallbackFcn(app, @CarBrandEditFieldValueChanged, true);
            app.CarModelEditFieldLabel = uilabel(app.UIFigure);
            app.CarModelEditFieldLabel.Text = 'Car Model';
            app.CarModelEditField = uieditfield(app.UIFigure, 'text');
            app.CarModelEditField.ValueChangedFcn = createCallbackFcn(app, @CarModelEditFieldValueChanged, true);
            app.CarYearofFabricationEditFieldLabel = uilabel(app.UIFigure);
            app.CarYearofFabricationEditFieldLabel.Text = 'Car Year of Fabrication';
            app.CarYearofFabricationEditField = uieditfield(app.UIFigure, 'numeric');
            app.CarYearofFabricationEditField.ValueChangedFcn = createCallbackFcn(app, @CarYearofFabricationEditFieldValueChanged, true);
            app.CarKilometersEditFieldLabel = uilabel(app.UIFigure);
            app.CarKilometersEditFieldLabel.Text = 'Car Kilometers';
            app.CarKilometersEditField = uieditfield(app.UIFigure, 'numeric');
            app.CarKilometersEditField.ValueChangedFcn = createCallbackFcn(app, @CarKilometersEditFieldValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MLA_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end