classdef MLA_GUI_Tab2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SeethePriceinLeiButton      matlab.ui.control.StateButton
        ThePredictedPriceEditField  matlab.ui.control.NumericEditField
        ThePredictedPriceLabel      matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        PriceEvaluationAppLabel     matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end

    
    properties (Access = private)
         CarsDataAfter;
         data;
    end


    methods (Access = public)
        
        function predicted = Prediction(app, rBrand, rModel, rYear, rKm)
            
            app.CarsDataAfter = readtable('ML Autovit/CarsData After.csv');
            app.data = app.CarsDataAfter;
            
            [userBrand, userModel, userYear, userKilometres] = app.reciveData(rBrand, rModel, rYear, rKm);
            

            % Feature scaling
            app.data.YearOfFabrication = zscore(app.data.YearOfFabrication);
            app.data.Kilometres = zscore(app.data.Kilometres);

            while true


                rowsWithSameBrand = strcmp(dataBrandAsString, userBrand);
                rowsWithSameModel = strcmp(dataModelAsString, userModel);
                % Find rows where both brand and model match the user input 
                searchRows = rowsWithSameBrand & rowsWithSameModel;
                dataFiltered = app.data(searchRows, :);

    
                if ~isempty(dataFiltered)
        
                    % 'PriceEuro' is the target variable
                    features = dataFiltered(:, {'Brand', 'Model', 'YearOfFabrication', 'Kilometres'});
                    target = dataFiltered.PriceEuro;

                    % Convert categorical variables to dummy variables
                    categoricalVariables = {'Brand', 'Model'};

                    for i = 1:length(categoricalVariables)
                        category = categorical(features.(categoricalVariables{i}));
                        dummyVariables = dummyvar(category);

                        nonZeroColumns = any(dummyVariables ~= 0, 1);
                        dummyVariables = dummyVariables(:, nonZeroColumns);

                        dummyTable = array2table(dummyVariables, 'VariableNames', strcat(categoricalVariables{i}, '_', string(1:size(dummyVariables, 2))));
                        features = [features, dummyTable];
                    end

                    % Remove the original categorical variables
                    features(:, categoricalVariables) = [];

                    % Add quadratic terms for 'YearOfFabrication' and 'Kilometres'
                    features.YearOfFabrication2 = features.YearOfFabrication.^2;
                    features.Kilometres2 = features.Kilometres.^2;

                    % Convert 'YearOfFabrication' and 'Kilometres' to doubles
                    features.YearOfFabrication = double(features.YearOfFabrication);
                    features.Kilometres = double(features.Kilometres);
                    % Convert 'YearOfFabrication2' and 'Kilometres2' to doubles
                    features.YearOfFabrication2 = double(features.YearOfFabrication2);
                    features.Kilometres2 = double(features.Kilometres2);
                    % Convert all variables to numeric arrays
                    features = [table2array(features)];

                    % Split the data into training 
                    cv = cvpartition(size(features, 1), 'HoldOut', 0);
                    idxTrain = training(cv);
                    % Create training and testing sets
                    XTrain = features(idxTrain, :);
                    YTrain = target(idxTrain);

                    % Train the machine learning model with elastic net regularization
                    mdl = fitrnet(XTrain, YTrain, "IterationLimit",100, 'Lambda', 0.1);
        
                    % Feature scaling for user input
                    userYear = zscore(userYear);
                    userKilometres = zscore(userKilometres);
                    % Add quadratic terms for user input
                    userYear2 = app.userYear^2;
                    userKilometers2 = app.userKilometres^2;

                    % Convert input into categorical
                    userBrandCat = categorical(cellstr(userBrand));
                    userModelCat = categorical(cellstr(userModel));
                    % Convert categorical variables to dummy variables
                    dummyBrand = dummyvar(double(userBrandCat));
                    dummyModel = dummyvar(double(userModelCat));

                    % Create a table with a single row for user input
                    userInput = table(userYear, userKilometres, userYear2, userKilometers2, dummyBrand, dummyModel, 'VariableNames', {'YearOfFabrication', 'Kilometres', 'YearOfFabrication2', 'Kilometres2', 'Brand', 'Model'});

                    % Predict the price for the user's input
                    predicted = predict(mdl, table2array(userInput));
                    predicted = round(predicted);

                    
                    break;
                else
                % Display an error dialog
                errordlg('Data not found, try again.', 'Error', 'modal');
            end
            end
  
        end
        
        function [userBrand,userModel, userYear,userKilometres] = reciveData(~, rBrand, rModel, rYear, rKm)
            userBrand = rBrand;
            userModel = rModel;
            userYear = rYear;
            userKilometres = rKm;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, userBrand)
            
        end

        % Value changed function: ThePredictedPriceEditField
        function ThePredictedPriceEditFieldValueChanged(app, event)
           % Receive user input from the stored instance of MLA_GUI
            [userBrand, userModel, userYear, userKilometres] = app.reciveData(MLA_GUI.userBrand, MLA_GUI.userModel, MLA_GUI.userYear, MLA_GUI.userKilometers);

            % Initialize any UI components or properties based on received data
            app.ThePredictedPriceEditField.Value = app.Prediction(userBrand, userModel, userYear, userKilometres);
        end

        % Value changed function: SeethePriceinLeiButton
        function SeethePriceinLeiButtonValueChanged(app, event)
            % Receive user input from the stored instance of MLA_GUI
            [userBrand, userModel, userYear, userKilometres] = app.reciveData(MLA_GUI.userBrand, MLA_GUI.userModel, MLA_GUI.userYear, MLA_GUI.userKilometers);

            value = app.SeethePriceinLeiButton.Value;
            if value == 1 && app.ThePredictedPriceEditField.Value ~= app.Prediction(userBrand, userModel, userYear, userKilometres) * 4.98
                app.ThePredictedPriceEditField.Value = app.Prediction(userBrand, userModel, userYear, userKilometres) * 4.98;
                app.SeethePriceinLeiButton.Text = 'See the Price in Euro';
            else
                app.SeethePriceinLeiButton.Text = 'See the Price in Lei';
                app.ThePredictedPriceEditField.Value = app.Prediction(userBrand, userModel, userYear, userKilometres) / 4.98;
            end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Open the first tab 
                    seconTab = app;  
                    firstTab = MLA_GUI; % Create an instance of the first tab class
                    firstTab.UIFigure.Visible = 'on'; % Make the first tab visible
                    seconTab.UIFigure.Visible = 'off';  % Make the second tab invisible
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'cars.jpeg');
            app.PriceEvaluationAppLabel = uilabel(app.UIFigure);
            app.PriceEvaluationAppLabel.Text = 'Price Evaluation App';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ThePredictedPriceLabel = uilabel(app.UIFigure);
            app.ThePredictedPriceLabel.Text = 'The Predicted Price: ';
            app.ThePredictedPriceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThePredictedPriceEditField.ValueChangedFcn = createCallbackFcn(app, @ThePredictedPriceEditFieldValueChanged, true);
            app.SeethePriceinLeiButton = uibutton(app.UIFigure, 'state');
            app.SeethePriceinLeiButton.ValueChangedFcn = createCallbackFcn(app, @SeethePriceinLeiButtonValueChanged, true);
            app.SeethePriceinLeiButton.Text = 'See the Price in Lei';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MLA_GUI_Tab2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end